classdef ImageClassification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        LoadImageButton    matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
        DetectImageButton  matlab.ui.control.Button
        UIAxes2            matlab.ui.control.UIAxes
        Label              matlab.ui.control.Label
    end

    
    properties (Access = private)
        myimage;% Description
    end
    

    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
              [file,path]=uigetfile({'*.jpg';'*.bmp';'*.gif';'*.png';'*.tiff'}, 'Select file');
              a= imread(strcat(path,file));
              a=imresize(a,[224,224]);
              app.myimage=a;
              imshow(a, 'parent', app.UIAxes);
        end

        % Button pushed function: DetectImageButton
        function DetectImageButtonPushed(app, event)
            % Access the trained model
            net = resnet50();
            % See details of the architecture
            %net.Layers
            % Read the image to classify
            
            app.myimage=imresize(app.myimage,[224,224]);
            % Adjust size of the image
            %sz = net.Layers(1).InputSize
            %I = I(1:sz(1),1:sz(2),1:sz(3));
            % Classify the image using Resnet-50
            label = classify(net, app.myimage);
            % Show the image and the classification results
            imshow(app.myimage,'parent',app.UIAxes2);
            text(10,20,char(label),'Color','red','FontSize',14,'FontWeight','bold','parent',app.UIAxes2);
            app.Label.Text=char(label);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Loaded Image')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.DetectImageButton = uibutton(app.UIFigure, 'push');
            app.DetectImageButton.ButtonPushedFcn = createCallbackFcn(app, @DetectImageButtonPushed, true);
            app.DetectImageButton.Text = {'Detect Image'; ''};
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Detected Image')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {''; ''};
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImageClassification

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end